classdef QUMIA < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        QUMIAUIFigure               matlab.ui.Figure
        Menu                        matlab.ui.container.Menu
        NewanalysisMenu             matlab.ui.container.Menu
        ContinueanalysisMenu        matlab.ui.container.Menu
        SaveMenu                    matlab.ui.container.Menu
        SendtoEPDMenu               matlab.ui.container.Menu
        PatientcharacteristicsMenu  matlab.ui.container.Menu
        ReportpreviewMenu           matlab.ui.container.Menu
        NormativemodelsMenu         matlab.ui.container.Menu
        Panel                       matlab.ui.container.Panel
        MeasurementSideButtonGroup  matlab.ui.container.ButtonGroup
        NotspecifiedButton          matlab.ui.control.RadioButton
        RightButton                 matlab.ui.control.RadioButton
        LeftButton                  matlab.ui.control.RadioButton
        ThicknessPanel              matlab.ui.container.Panel
        RemoveTHButton              matlab.ui.control.Button
        AddTHButton                 matlab.ui.control.Button
        dispTH                      matlab.ui.control.NumericEditField
        RegionofinterestPanel       matlab.ui.container.Panel
        dispEI                      matlab.ui.control.NumericEditField
        RemoveROIButton             matlab.ui.control.Button
        AddROIButton                matlab.ui.control.Button
        MusclePanel                 matlab.ui.container.Panel
        MuscleList                  matlab.ui.control.ListBox
        ImagePanel                  matlab.ui.container.Panel
        measurementList             matlab.ui.control.ListBox
        UIAxes                      matlab.ui.control.UIAxes
    end

    
    properties (Access = private)

        config                                                              % configuration
        pop                                                                 % app population
        currentMes = 1                                                      % pointer to active measurement
        im                                                                  % US image list
        measurements                                                        % calculated outcomes
        subject                                                             % subject characteristics
        models                                                              % Normative models
        report                                                              % reporting data
        bodyChartCoords
        annotatedBodyIm

        patientCharacteristics                                              % Patient characteristics dialog box
        reportPreview                                                       % Report preview dialog box
        normativeModels                                                     % Normative models dialog box
    end
    
    methods (Access = private)

        function results = PopulateSelection(app)
            
            app.measurementList.Items = app.pop.dispName;
            app.measurementList.Value = app.measurementList.Items(find(strcmp(app.pop.dispName{app.currentMes}, app.measurementList.Items)));

        end
        
        function results = loadMeasurements(app)
            
            if ~isempty(app.im{app.currentMes})

                x = 1:size(app.im{app.currentMes}, 2);
                y = 1:size(app.im{app.currentMes}, 1);
                imagesc(app.UIAxes, x, y, app.im{app.currentMes})
                xlim(app.UIAxes, [x(1), x(end)])
                ylim(app.UIAxes, [y(1), y(end)])
    
                if ~isempty(app.measurements.vertices{app.currentMes})
    
                    hold(app.UIAxes, 'on')
                    plot(app.UIAxes, app.measurements.vertices{app.currentMes}{1}(:, 2), app.measurements.vertices{app.currentMes}{1}(:, 1), 'Color', [0.4660 0.6740 0.1880], 'Linewidth', 2)
                    hold(app.UIAxes, 'off')
    
                end

                if ~isempty(app.measurements.lineVertices{app.currentMes})
    
                    hold(app.UIAxes, 'on')
                    plot(app.UIAxes, app.measurements.lineVertices{app.currentMes}(:, 1), app.measurements.lineVertices{app.currentMes}(:, 2), 'Color', [0.3010 0.7450 0.9330], 'Linewidth', 2)
                    hold(app.UIAxes, 'off')
    
                end

                button = find(strcmp({app.MeasurementSideButtonGroup.Buttons.Text}, app.measurements.Side(app.currentMes)));
                app.MeasurementSideButtonGroup.Buttons(button).Value = 1;

                if ~isnan(app.measurements.(['EI' app.config.summaryStatistic])(app.currentMes))

                    app.dispEI.Value = app.measurements.(['EI' app.config.summaryStatistic])(app.currentMes);

                else

                    app.dispEI.Value = 0;

                end

                if ~isnan(app.measurements.Thickness(app.currentMes))

                    app.dispTH.Value = app.measurements.Thickness(app.currentMes);

                else

                    app.dispTH.Value = 0;

                end


                if ~isempty(app.measurements.Muscle{app.currentMes})

                    app.MuscleList.Value = app.MuscleList.Items(find(strcmp(app.measurements.Muscle{app.currentMes}, app.MuscleList.Items)));

                else

                    app.MuscleList.Value = {};

                end

            else

                imagesc(app.UIAxes, [])

            end

        end

        function results = changeDispName(app)

            if strcmp(app.measurements.Muscle{app.currentMes}, 'No analysis')
                
                app.pop.dispName{app.currentMes} = [num2str(app.currentMes) ' - ' app.measurements.Muscle{app.currentMes}];

            else

                app.pop.dispName{app.currentMes} = [num2str(app.currentMes) ' - (' app.measurements.Side{app.currentMes} ') ' app.measurements.Muscle{app.currentMes}];

            end

            app.PopulateSelection();
            app.loadMeasurements();

        end

        
        function results = calculateReportData(app)
            
            reportMuscles = unique(app.measurements.Muscle(~cellfun(@isempty, app.measurements.Muscle)));
            sides = {'Right', 'Left', 'Not specified'};

            app.report.Muscle = cell(size(reportMuscles, 2) * 3, 1);
            app.report.Side = cell(size(reportMuscles, 2) * 3, 1);
            app.report.avgEI = NaN(size(reportMuscles, 2) * 3, 1);
            app.report.predictedEI = NaN(size(reportMuscles, 2) * 3, 1);
            app.report.zScoreEI = NaN(size(reportMuscles, 2) * 3, 1);
            app.report.avgTH = NaN(size(reportMuscles, 2) * 3, 1);
            app.report.predictedTH = NaN(size(reportMuscles, 2) * 3, 1);
            app.report.zScoreTH = NaN(size(reportMuscles, 2) * 3, 1);
            app.report.measurementsEI = cell(size(reportMuscles, 2) * 3, 1);
            app.report.measurementsTH = cell(size(reportMuscles, 2) * 3, 1);

            cnt = 1;

            for i = 1:numel(reportMuscles)

                if ~strcmp(reportMuscles{i}, 'No analysis')

                    for j = 1:3
    
                        app.report.Muscle{cnt} = reportMuscles{i};
                        app.report.Side{cnt} = sides{j};
                        app.report.measurementsEI{cnt} = app.measurements.(['EI' app.config.summaryStatistic])(strcmp(app.measurements.Muscle', reportMuscles{i}) & strcmp(app.measurements.Side, sides{j}));
                        app.report.avgEI(cnt) = nanmean(app.report.measurementsEI{cnt});
                        app.report.measurementsTH{cnt} = app.measurements.Thickness(strcmp(app.measurements.Muscle', reportMuscles{i}) & strcmp(app.measurements.Side, sides{j}));
                        app.report.avgTH(cnt) = nanmean(app.report.measurementsTH{cnt});
                        
                        if strcmp(app.config.normativeModelAvailable, 'yes')
        
                            
                            alpha = 0.05;
       
                            if strcmp(app.subject.Sex, 'Male')

                                Sex = {'M'};

                            elseif strcmp(app.subject.Sex, 'Female')

                                Sex = {'F'};

                            end
        
                            % Echogenicity
                            modelNumberEI = find(strcmp(app.models.EImdls.Muscle, reportMuscles{i}));

                            if ~isempty(modelNumberEI)

                                % centre and scale
                                csAge = (app.subject.Age - app.models.EImdls.meanAge(modelNumberEI)) / app.models.EImdls.sdAge(modelNumberEI);
                                csBMI = (app.subject.BMI - app.models.EImdls.meanBMI(modelNumberEI)) / app.models.EImdls.sdBMI(modelNumberEI);
                                cAge2 = (app.subject.Age - app.models.EImdls.meanAge(modelNumberEI)) .^ 2;
                                csAge2 = (cAge2 - app.models.EImdls.meanAge2(modelNumberEI)) / app.models.EImdls.sdAge2(modelNumberEI);
                                cBMI2 = (app.subject.BMI - app.models.EImdls.meanBMI(modelNumberEI)) .^ 2;
                                csBMI2 = (cBMI2 - app.models.EImdls.meanBMI2(modelNumberEI)) / app.models.EImdls.sdBMI2(modelNumberEI);
                                predictModelDataEI = table(csAge, csAge2, csBMI, csBMI2, Sex);
        
                                [app.report.predictedEI(cnt), CIEI] = predict(app.models.EImdls.Model{modelNumberEI}, predictModelDataEI, 'Prediction', 'observation');
                                t_value = tinv(1-(alpha/2),app.models.EImdls.Model{modelNumberEI}.DFE);
                                margin_error = CIEI(2) - app.report.predictedEI(cnt);
                                app.report.zScoreEI(cnt) = (app.report.avgEI(cnt) - app.report.predictedEI(cnt)) / (margin_error / t_value);

                            end
        
                            % Thickness
                            modelNumberTH = find(strcmp(app.models.THmdls.Muscle, reportMuscles{i}));

                            if ~isempty(modelNumberTH)

                                % centre and scale
                                csAge = (app.subject.Age - app.models.THmdls.meanAge(modelNumberTH)) / app.models.THmdls.sdAge(modelNumberTH);
                                csBMI = (app.subject.BMI - app.models.THmdls.meanBMI(modelNumberTH)) / app.models.THmdls.sdBMI(modelNumberTH);
                                cAge2 = (app.subject.Age - app.models.THmdls.meanAge(modelNumberTH)) .^ 2;
                                csAge2 = (cAge2 - app.models.THmdls.meanAge2(modelNumberTH)) / app.models.THmdls.sdAge2(modelNumberTH);
                                cBMI2 = (app.subject.BMI - app.models.THmdls.meanBMI(modelNumberTH)) .^ 2;
                                csBMI2 = (cBMI2 - app.models.THmdls.meanBMI2(modelNumberTH)) / app.models.THmdls.sdBMI2(modelNumberTH);
                                
                                if strcmp(app.subject.Handedness, sides{j})

                                    Dominance = {'Dominant'};

                                else

                                    Dominance = {'Non-dominant'};

                                end

                                predictModelDataTH = table(csAge, csAge2, csBMI, csBMI2, Sex, Dominance);

                                [app.report.predictedTH(cnt), CITH] = predict(app.models.THmdls.Model{modelNumberTH}, predictModelDataTH, 'Prediction', 'observation');
                                t_value = tinv(1-(alpha/2),app.models.THmdls.Model{modelNumberTH}.DFE);
                                margin_error = CITH(2) - app.report.predictedTH(cnt);
                                app.report.zScoreTH(cnt) = (app.report.avgTH(cnt) - app.report.predictedTH(cnt)) / (margin_error / t_value);

                            end
    
                        end
        
                        cnt = cnt + 1;
    
                    end

                end

            end

            emptyRecords = isnan(app.report.avgEI) & isnan(app.report.avgTH);

            app.report.Muscle(emptyRecords) = [];
            app.report.Side(emptyRecords) = [];
            app.report.avgEI(emptyRecords) = [];
            app.report.predictedEI(emptyRecords) = [];
            app.report.zScoreEI(emptyRecords) = [];
            app.report.avgTH(emptyRecords) = [];
            app.report.predictedTH(emptyRecords) = [];
            app.report.zScoreTH(emptyRecords) = [];
            app.report.measurementsEI(emptyRecords) = [];
            app.report.measurementsTH(emptyRecords) = [];

        end

        
        function annotation = getAnnotationValue(app, zScore)

            if zScore >= 3.0

                annotation = 50;
    
            elseif zScore >= 2.5
    
                annotation = 100;
    
            elseif zScore >= 2.0
    
                annotation = 150;
    
            elseif zScore >= 1.5
    
                annotation = 200;
    
            else
    
                annotation = 255;
    
            end
            
        end

    end
    
    methods (Access = public)
        
        function results = updateSubject(app, subject)
            
            app.subject = subject;
            app.subject.Age = years(app.pop.examDate-app.subject.DoB);
            app.subject.BMI = app.subject.Weight / ((app.subject.Length / 100) * (app.subject.Length / 100));

        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function Initialize(app)
            
            fid = fopen('QUMIA_config.txt');
            
            while ~feof(fid)
                
                params = strsplit(fgetl(fid), '=');
                
                if isnan(str2double(params{2}))
                    
                    if contains(params{2}, ',')

                        app.config.(params{1}) = strsplit(params{2}, ',');

                    elseif contains(params{2}, ';')

                        app.config.(params{1}) = str2double(strsplit(params{2}, ';'));

                    else
                    
                        app.config.(params{1}) = params{2};

                    end
                    
                else
                    
                    app.config.(params{1}) = str2double(params{2});
                    
                end
                
            end
            
            fclose(fid);

            availableMuscles = readtable(app.config.muscleList);
            availableMuscles.Muscle{numel(availableMuscles.Muscle) + 1} = 'No analysis';
            app.MuscleList.Items = availableMuscles.Muscle;

            if strcmp(app.config.normativeModelAvailable, 'yes')

                loadObj = load(app.config.normativeModels);
                app.models = loadObj;

                app.NormativemodelsMenu.Enable = 'on';
    
            end

            loadObj = load(app.config.bodyChartCoords);
            app.bodyChartCoords = loadObj;

            app.annotatedBodyIm = imread(app.config.annotatedBodyIm);

        end

        % Menu selected function: NewanalysisMenu
        function loadData(app, event)
            % Define visit data
            app.pop.path = uigetdir(app.config.defaultFolder);
            files = dir(app.pop.path);
            files = files(~ismember({files.name}, {'.', '..', 'Thumbs.db'}));

            d = uiprogressdlg(app.QUMIAUIFigure,'Title', 'Please Wait', 'Message', 'Loading images');
            
            remove = false(size(files, 1), 1);

            for i = 1:size(files, 1)

                d.Value = i/size(files, 1);
                app.pop.dispName{i} = num2str(i);
                app.pop.assFile{i}  = [app.pop.path '\' files(i).name];

                info = dicominfo(app.pop.assFile{i});
                app.pop.pixelSpacing(i) = info.SequenceOfUltrasoundRegions.Item_1.PhysicalDeltaY;
                app.pop.depth(i) = numel(info.SequenceOfUltrasoundRegions.Item_1.RegionLocationMinY0:info.SequenceOfUltrasoundRegions.Item_1.RegionLocationMaxY1) * info.SequenceOfUltrasoundRegions.Item_1.PhysicalDeltaY;

                if ~isfield(info, 'StartTrim')

                    imTemp = dicomread(app.pop.assFile{i});
    
                    if size(imTemp, 3) == 3
    
                        app.im{i} = rgb2gray(imTemp);
    
                    else
    
                        app.im{i} = imTemp;
    
                    end

                else

                    app.im{i} = NaN;
                    remove(i) = true; 

                end

            end

            app.pop.dispName(remove) = [];
            app.pop.assFile(remove) = [];
            app.pop.pixelSpacing(remove) = [];
            app.pop.depth(remove) = [];
            app.im(remove) = [];

                       
            app.pop.examDate = datetime(info.StudyDate, 'InputFormat', 'yyyyMMdd'); %%Changed to studydate

            % pre-allocate
            app.measurements.annotatedIm = cell(size(app.pop.dispName));
            app.measurements.EIavg = NaN(size(app.pop.dispName));
            app.measurements.EIsd = NaN(size(app.pop.dispName));
            app.measurements.EIp05 = NaN(size(app.pop.dispName));
            app.measurements.EIp25 = NaN(size(app.pop.dispName));
            app.measurements.EIp50 = NaN(size(app.pop.dispName));
            app.measurements.EIp75 = NaN(size(app.pop.dispName));
            app.measurements.EIp95 = NaN(size(app.pop.dispName));
            app.measurements.EImode = NaN(size(app.pop.dispName));
            app.measurements.AvgDepth = NaN(size(app.pop.dispName));
            app.measurements.SdDepth = NaN(size(app.pop.dispName));
            app.measurements.Thickness = NaN(size(app.pop.dispName));
            app.measurements.mask = cell(size(app.pop.dispName));
            app.measurements.vertices = cell(size(app.pop.dispName));
            app.measurements.lineVertices = cell(size(app.pop.dispName));
            app.measurements.Muscle = cell(size(app.pop.dispName));
            app.measurements.Side = repelem({'Not specified'}, numel(app.pop.dispName), 1);

            % app controls
            app.PopulateSelection();
            app.currentMes = 1;
            app.loadMeasurements();
            app.NewanalysisMenu.Enable = 'off';
            app.ContinueanalysisMenu.Enable = 'off';

            if isfield(info, 'PatientBirthDate')
                
                app.subject.DoB = datetime(info.PatientBirthDate,  'Inputformat', 'yyyyMMdd');
                
            else
                
                app.subject.DoB = NaT;
                
            end
                
            if isfield(info, 'PatientID')
                
                app.subject.ID = info.PatientID;
                
            else
                
                app.subject.ID = 'N/A';
                
            end

             if isfield(info, 'PatientName')                               %% toegevoegd
                
                app.subject.PatientName = info.PatientName;
                
            else
                
                app.subject.ID = 'N/A';
                
             end

                 if isfield(info, 'AccessionNumber')                       %% toegevoegd
                
                app.subject.AccessionNumber = info.AccessionNumber;
                
            else
                
                app.subject.AccessionNumber = 'N/A';
                
            end
 
            if isfield(info, 'ManufacturerModelName')                       %% toegevoegd
                
                app.subject.ManufacturerModelName = info.ManufacturerModelName;
                
            else
                
                app.subject.ManufacturerModelName = 'N/A';
                
            end
            
            if isfield(info, 'PatientSex')

                if strcmp(info.PatientSex, 'M')
    
                    app.subject.Sex = 'Male';
    
                elseif strcmp(info.PatientSex, 'F')
    
                    app.subject.Sex = 'Female';

                else

                    app.subject.Sex = 'Male';
    
                end
                
            else
                
                app.subject.Sex = 'Male';
                
            end
            
            if isfield(info, 'PatientWeight')
                
                if ~isempty(info.PatientWeight)

                    app.subject.Weight = info.PatientWeight;

                else

                    app.subject.Weight = 0;

                end
                
            else
                
                app.subject.Weight = 0;
                
            end
                        
            if isfield(info, 'PatientSize')
                
                if ~isempty(info.PatientSize)

                    app.subject.Length = info.PatientSize;

                else

                   app.subject.Length = 0;

                end
                
            else
                
                app.subject.Length = 0;
                
            end
            
            app.subject.Handedness = 'Unknown';
            app.subject.Technician = '0';
            
            app.openPatientsCharacteristics()
            close(d);

        end

        % Menu selected function: SaveMenu
        function save(app, event)

            d = uiprogressdlg(app.QUMIAUIFigure,'Title', 'Please Wait', 'Message', 'Saving analysis results');

            if ~exist([app.pop.path '\Saves'], 'dir')

                mkdir([app.pop.path '\Saves'])

            end

            if ~exist([app.pop.path '\DICOM images'], 'dir')

                mkdir([app.pop.path '\DICOM images'])

            end

            if ~exist([app.pop.path '\Images'], 'dir')

                mkdir([app.pop.path '\Images'])

            end

            for i = 1:numel(app.pop.dispName)
                
                d.Value = i / (numel(app.pop.dispName)+3);

                newFileName = [app.pop.path '\DICOM images\' app.pop.dispName{i}];
                if ~strcmp(app.pop.assFile{i}, newFileName)
                    
                    movefile(app.pop.assFile{i}, newFileName);
                    
                end
                app.pop.assFile{i} = newFileName;

                hFig = figure('Visible', 'off');
                hAx = axes(hFig);
                x = 1:size(app.im{i}, 2);
                y = 1:size(app.im{i}, 1);
                imagesc(hAx, x, y, app.im{i})
                xlim(hAx, [x(1), x(end)])
                ylim(hAx, [y(1), y(end)])
                set(gcf, 'Position', [100, 100, x(end), y(end)])
                set(hAx, 'Position', [0, 0, 1, 1])
    
                if ~isempty(app.measurements.vertices{i})
    
                    hold(hAx, 'on')
                    plot(hAx, app.measurements.vertices{i}{1}(:, 2), app.measurements.vertices{i}{1}(:, 1), 'Color', [0.4660 0.6740 0.1880], 'Linewidth', 2)
                    hold(hAx, 'off')
    
                end

                if ~isempty(app.measurements.lineVertices{i})
    
                    hold(hAx, 'on')
                    plot(hAx, app.measurements.lineVertices{i}(:, 1), app.measurements.lineVertices{i}(:, 2), 'Color', [0.3010 0.7450 0.9330], 'Linewidth', 2)
                    hold(hAx, 'off')
    
                end

                colormap(gray)
                hAx.XTick = [];
                hAx.XTickLabel = [];
                hAx.YTick = [];
                hAx.YTickLabel = [];
                
                imwrite(getframe(hAx).cdata, [app.pop.path '\Images\' app.pop.dispName{i} '.tif']);
                close(hFig)

            end

            d.Value = (numel(app.pop.dispName)+1) / (numel(app.pop.dispName)+3);

            app.calculateReportData();

            d.Value = (numel(app.pop.dispName)+2) / (numel(app.pop.dispName)+3);

            Tsummary = table(app.report.Muscle, app.report.Side, ...
                             app.report.avgEI, app.report.predictedEI, app.report.zScoreEI, ...
                             app.report.avgTH, app.report.predictedTH, app.report.zScoreTH);
            Tsummary.Properties.VariableNames = {'Muscle', 'Side', 'EI - observed', 'EI - predicted', 'EI Z score', 'TH - observed', 'TH - predicted', 'TH - Z score'};

            measEI = app.report.measurementsEI;
            maxCols = max(cellfun(@numel, measEI));
            expandedMeasEI = NaN(numel(measEI), maxCols);

            for i = 1:numel(measEI)

                cellContent = measEI{i};

                if ~isempty(cellContent)

                    expandedMeasEI(i, 1:numel(cellContent)) = cellContent;

                end

            end

            tMeasEI = array2table(expandedMeasEI, 'VariableNames', strcat('EI_', string(1:maxCols)));

            measTH = app.report.measurementsTH;
            maxCols = max(cellfun(@numel, measTH));
            expandedMeasTH = NaN(numel(measTH), maxCols);

            for i = 1:numel(measTH)

                cellContent = measTH{i};

                if ~isempty(cellContent)

                    expandedMeasTH(i, 1:numel(cellContent)) = cellContent;

                end

            end
            
            tMeasTH = array2table(expandedMeasTH, 'VariableNames', strcat('TH_', string(1:maxCols)));

            Tdetails = [table(app.report.Muscle, app.report.Side, 'VariableNames', {'Muscle', 'Side'}) ...
                              tMeasEI ...
                              table(app.report.avgEI, app.report.predictedEI, app.report.zScoreEI, 'VariableNames', {'EI - observed', 'EI - predicted', 'EI Z score'}) ...
                              tMeasTH ...
                              table(app.report.avgTH, app.report.predictedTH, app.report.zScoreTH, 'VariableNames', {'TH - observed', 'TH - predicted', 'TH - Z score'})];

            Tsubject = table({'ID', 'Date of birth', 'Age at examination (y)', 'Length (cm)', 'Weight (kg)', 'BMI (kg/m2)', 'Sex', 'Handedness', 'Technician'}', ...
                             {app.subject.ID, app.subject.DoB, years(app.pop.examDate-app.subject.DoB), app.subject.Length, app.subject.Weight, ...
                              app.subject.Weight/((app.subject.Length / 100) * (app.subject.Length / 100)), app.subject.Sex, app.subject.Handedness, ...
                              app.subject.Technician}');
            Tsubject.Properties.VariableNames = {'Characteristic', 'Value'};                
            
            writetable(Tsummary, [app.pop.path '\Saves\Analysis results.xlsx'], 'sheet', 'Summary');
            writetable(Tdetails, [app.pop.path '\Saves\Analysis results.xlsx'], 'sheet', 'Details');
            writetable(Tsubject, [app.pop.path '\Saves\Analysis results.xlsx'], 'sheet', 'Subject characteristics');

            for i = 1:numel(app.report.Side)

                if ~isempty(find(strcmp(app.bodyChartCoords.coords.muscle, app.report.Muscle{i})))

                    muscleIdx = find(strcmp(app.bodyChartCoords.coords.muscle, app.report.Muscle{i}));

                    if strcmp(app.report.Side{i}, 'Right')
                        
                        app.annotatedBodyIm(app.bodyChartCoords.coords.maskR{muscleIdx}) = app.getAnnotationValue(app.report.zScoreEI(i));
    
                    elseif strcmp(app.report.Side{i}, 'Left')

                        app.annotatedBodyIm(app.bodyChartCoords.coords.maskL{muscleIdx}) = app.getAnnotationValue(app.report.zScoreEI(i));
    
                    end

                end

            end
            
            imwrite(app.annotatedBodyIm, [app.pop.path '\Saves\bodyChart.jpg'])


            d.Value = (numel(app.pop.dispName)+3) / (numel(app.pop.dispName)+3);

            % Not needed: currentMes, models, dialog apps (patientCharacteristics, reportPreview, normativeModels)
            sav.config = app.config;
            sav.pop = app.pop;
            sav.im = app.im;
            sav.measurements = app.measurements;
            sav.subject = app.subject;

            save([app.pop.path '\Saves\Analysis save.mat'], 'sav');
            close(d)

            app.SendtoEPDMenu.Enable = 'on';
        
        end

        % Menu selected function: ContinueanalysisMenu
        function importData(app, event)

            path = uigetdir(app.config.defaultFolder);
            
            load([path '/Saves/Analysis save.mat']);
            
            app.config = sav.config;
            app.pop = sav.pop;
            app.im = sav.im;
            app.measurements = sav.measurements;
            app.subject = sav.subject;

            app.PopulateSelection();
            app.currentMes = 1;
            app.loadMeasurements();
          
            app.PatientcharacteristicsMenu.Enable = 'on';
            app.SaveMenu.Enable = 'on';
            app.ReportpreviewMenu.Enable = 'on';
            app.MuscleList.Enable = 'on';
            app.measurementList.Enable = 'on';
            app.MeasurementSideButtonGroup.Enable = 'on';
            app.AddROIButton.Enable = 'on';
            app.RemoveROIButton.Enable = 'on';
            app.AddTHButton.Enable = 'on';
            app.RemoveTHButton.Enable = 'on';
            app.dispTH.Enable = 'on';

        end

        % Value changed function: measurementList
        function updateCurrentMes(app, event)

            app.currentMes = find(strcmp(app.pop.dispName, app.measurementList.Value));
            app.loadMeasurements();
            
        end

        % Button pushed function: AddROIButton
        function drawROI(app, event)
            
            app.measurements.mask(app.currentMes) = cell(1);
            app.measurements.vertices(app.currentMes) = cell(1);

            app.loadMeasurements();

            h = drawpolygon(app.UIAxes, 'Color', [0.4660 0.6740 0.1880], 'InteractionsAllowed', 'none');
            app.measurements.mask{app.currentMes} = createMask(h);
            preset = find(round(app.config.depth, 4) == round(app.pop.depth(app.currentMes), 4));
            leftBorder = app.config.left(preset);
            rightBorder = app.config.right(preset);
            leftMargin = round((app.config.margin * numel(leftBorder:rightBorder))) + leftBorder;
            rightMargin = rightBorder - round((app.config.margin * numel(leftBorder:rightBorder)));
            app.measurements.mask{app.currentMes}([1:app.config.top(preset) app.config.bottom(preset):end], :) = false;
            app.measurements.mask{app.currentMes}(:, [1:leftMargin rightMargin:end]) = false;
            app.measurements.vertices{app.currentMes} = bwboundaries(app.measurements.mask{app.currentMes});
            delete(h);

            app.measurements.EIavg(app.currentMes) = mean(app.im{app.currentMes}(app.measurements.mask{app.currentMes}));
            app.measurements.EIsd(app.currentMes) = std(double(app.im{app.currentMes}(app.measurements.mask{app.currentMes})));
            app.measurements.EIp05(app.currentMes) = quantile(app.im{app.currentMes}(app.measurements.mask{app.currentMes}), 0.05);
            app.measurements.EIp25(app.currentMes) = quantile(app.im{app.currentMes}(app.measurements.mask{app.currentMes}), 0.25);
            app.measurements.EIp50(app.currentMes) = quantile(app.im{app.currentMes}(app.measurements.mask{app.currentMes}), 0.50);
            app.measurements.EIp75(app.currentMes) = quantile(app.im{app.currentMes}(app.measurements.mask{app.currentMes}), 0.75);
            app.measurements.EIp95(app.currentMes) = quantile(app.im{app.currentMes}(app.measurements.mask{app.currentMes}), 0.95);
            
            greyRange = 0:255;
            dist = pdf(fitdist(double(app.im{app.currentMes}(app.measurements.mask{app.currentMes})), 'kernel'), greyRange);
            [~, pos] = max(dist);
            app.measurements.EImode(app.currentMes) = greyRange(pos);

            [depth, ~] = find(app.measurements.mask{app.currentMes});
            app.measurements.AvgDepth(app.currentMes) = mean(depth) * app.pop.pixelSpacing(app.currentMes);
            app.measurements.SdDepth(app.currentMes) = std(depth) * app.pop.pixelSpacing(app.currentMes);

            app.loadMeasurements();

        end

        % Button pushed function: RemoveROIButton
        function removeROI(app, event)
            
            app.measurements.mask(app.currentMes) = cell(1);
            app.measurements.vertices(app.currentMes) = cell(1);

            app.measurements.EIavg(app.currentMes) = NaN;
            app.measurements.EIsd(app.currentMes) = NaN;
            app.measurements.EIp05(app.currentMes) = NaN;
            app.measurements.EIp25(app.currentMes) = NaN;
            app.measurements.EIp50(app.currentMes) = NaN;
            app.measurements.EIp75(app.currentMes) = NaN;
            app.measurements.EIp95(app.currentMes) = NaN;
            app.measurements.EImode(app.currentMes) = NaN;
            app.measurements.AvgDepth(app.currentMes) = NaN;
            app.measurements.SdDepth(app.currentMes) = NaN;

            app.loadMeasurements();

        end

        % Button pushed function: AddTHButton
        function addThickness(app, event)

            app.measurements.lineVertices(app.currentMes) = cell(1);

            app.loadMeasurements();

            h = drawline(app.UIAxes, 'Color', [0.3010 0.7450 0.9330], 'InteractionsAllowed', 'none');
            app.measurements.lineVertices{app.currentMes} = h.Position();
            delete(h);

            app.measurements.Thickness(app.currentMes) = sqrt(((abs(diff(app.measurements.lineVertices{app.currentMes}(:, 1))) * app.pop.pixelSpacing(app.currentMes))^2) + ...
                                                              ((abs(diff(app.measurements.lineVertices{app.currentMes}(:, 2))) * app.pop.pixelSpacing(app.currentMes))^2));
    

            app.loadMeasurements();

        end

        % Button pushed function: RemoveTHButton
        function removeThickness(app, event)

            app.measurements.lineVertices(app.currentMes) = cell(1);
            app.measurements.Thickness(app.currentMes) = NaN;

            app.loadMeasurements();

        end

        % Value changed function: MuscleList
        function associateMuscle(app, event)
            
            app.measurements.Muscle{app.currentMes} = app.MuscleList.Value;
            app.changeDispName();

        end

        % Selection changed function: MeasurementSideButtonGroup
        function changeMeasurementSide(app, event)
                        
            app.measurements.Side{app.currentMes} = app.MeasurementSideButtonGroup.SelectedObject.Text;
            app.changeDispName();

        end

        % Value changed function: dispTH
        function changeThicknessManually(app, event)

            app.measurements.lineVertices(app.currentMes) = cell(1);
            app.measurements.Thickness(app.currentMes) = app.dispTH.Value;

            app.loadMeasurements();

        end

        % Menu selected function: PatientcharacteristicsMenu
        function openPatientsCharacteristics(app, event)
            
            app.PatientcharacteristicsMenu.Enable = 'off';

            app.patientCharacteristics = patientCharacteristics(app, app.subject);

        end

        % Menu selected function: ReportpreviewMenu
        function openReportPreview(app, event)
            
            app.ReportpreviewMenu.Enable = 'off';

            app.calculateReportData();
            app.reportPreview = reportPreview(app, app.report);
            
        end

        % Menu selected function: NormativemodelsMenu
        function openNormativeModels(app, event)
            
            app.NormativemodelsMenu.Enable = 'off';
            app.normativeModels = normativeModels(app, app.models, app.config);

        end

        % Close request function: QUMIAUIFigure
        function appCloseRequestFcn(app, event)
            
            delete(app.patientCharacteristics);
            delete(app.reportPreview);
            delete(app.normativeModels);
            delete(app);
            
        end

        % Menu selected function: SendtoEPDMenu
        function sendToEPD(app, event)
                        
            % custom code for integration with institute digital
            % architecture
            
             % paths
            tifpath = [app.pop.path '\Images\'];
            tifnum = dir(fullfile(tifpath,'**\*.tif'));
            outpath = [app.pop.path '\results.xml'] ;
            imppath = [app.config.outputFolder '\results.xml'];
             % Create xml tree
             
                qumia_xml.accession_number = app.subject.AccessionNumber;
                qumia_xml.patient.patientid = app.subject.ID;
                qumia_xml.patient.name = app.subject.PatientName.FamilyName;
                qumia_xml.patient.geboortedatum = app.subject.DoB;
                qumia_xml.patient.lengte = app.subject.Length;
                qumia_xml.patient.gewicht = app.subject.Weight;
                qumia_xml.patient.geslacht = app.subject.Sex;
                qumia_xml.patient.leeftijd = app.subject.Age;
                qumia_xml.patient.meetdatum = app.pop.examDate;

                for m = 1:size(app.report.Muscle)
                                                      
                       qumia_xml.muscle(m).muscle_name = string(app.report.Muscle{m});
                       qumia_xml.muscle(m).side = string(app.report.Side{m});

                       qumia_xml.muscle(m).EI = string(app.report.avgEI(m));
                       qumia_xml.muscle(m).EI_normal = string(app.report.predictedEI(m)); 
                       qumia_xml.muscle(m).EI_zscore = string(app.report.zScoreEI(m));
                            
                       qumia_xml.muscle(m).thickness = string(app.report.avgTH(m));
                       qumia_xml.muscle(m).thickness_normal = string(app.report.predictedTH(m));
                       qumia_xml.muscle(m).thickness_zscore = string(app.report.zScoreTH(m));
                               
                end
                                
                qumia_xml.laborant.naam = app.subject.Technician;
                qumia_xml.machine = app.subject.ManufacturerModelName;
                qumia_xml.qumiaversion = 'Qumia_V4.0';
                qumia_xml.zscore_image = [app.pop.path '\Saves\bodyChart.jpg'];
                
                 for i=1:size(tifnum,1)
                    qumia_xml.image{i} = fullfile(tifpath,tifnum(i).name);
                 end

            % Write struct to XML
            writestruct(qumia_xml,outpath);
            writestruct(qumia_xml,imppath);
                                  
            % app.subject: all meta-information on the scanned subject
            % app.report: all analysed data

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.QUMIAUIFigure = uifigure('Visible', 'off');
            colormap(app.QUMIAUIFigure, 'gray');
            app.QUMIAUIFigure.Name = 'QUMIA';
            app.QUMIAUIFigure.CloseRequestFcn = createCallbackFcn(app, @appCloseRequestFcn, true);
            app.Menu = uimenu(app.QUMIAUIFigure);
            app.Menu.Text = 'Menu';
            app.NewanalysisMenu = uimenu(app.Menu);
            app.NewanalysisMenu.MenuSelectedFcn = createCallbackFcn(app, @loadData, true);
            app.NewanalysisMenu.Text = 'New analysis';
            app.ContinueanalysisMenu = uimenu(app.Menu);
            app.ContinueanalysisMenu.MenuSelectedFcn = createCallbackFcn(app, @importData, true);
            app.ContinueanalysisMenu.Text = 'Continue analysis';
            app.SaveMenu = uimenu(app.Menu);
            app.SaveMenu.MenuSelectedFcn = createCallbackFcn(app, @save, true);
            app.SaveMenu.Enable = 'off';
            app.SaveMenu.Text = 'Save';
            app.SendtoEPDMenu = uimenu(app.Menu);
            app.SendtoEPDMenu.MenuSelectedFcn = createCallbackFcn(app, @sendToEPD, true);
            app.SendtoEPDMenu.Enable = 'off';
            app.SendtoEPDMenu.Text = 'Send to EPD';
            app.PatientcharacteristicsMenu = uimenu(app.QUMIAUIFigure);
            app.PatientcharacteristicsMenu.MenuSelectedFcn = createCallbackFcn(app, @openPatientsCharacteristics, true);
            app.PatientcharacteristicsMenu.Enable = 'off';
            app.PatientcharacteristicsMenu.Text = 'Patient characteristics';
            app.ReportpreviewMenu = uimenu(app.QUMIAUIFigure);
            app.ReportpreviewMenu.MenuSelectedFcn = createCallbackFcn(app, @openReportPreview, true);
            app.ReportpreviewMenu.Enable = 'off';
            app.ReportpreviewMenu.Text = 'Report preview';
            app.NormativemodelsMenu = uimenu(app.QUMIAUIFigure);
            app.NormativemodelsMenu.MenuSelectedFcn = createCallbackFcn(app, @openNormativeModels, true);
            app.NormativemodelsMenu.Enable = 'off';
            app.NormativemodelsMenu.Text = 'Normative models';
            app.UIAxes = uiaxes(app.QUMIAUIFigure);
            app.UIAxes.XTick = [];
            app.UIAxes.XTickLabel = '';
            app.UIAxes.YTick = [];
            colormap(app.UIAxes, 'gray')
            app.Panel = uipanel(app.QUMIAUIFigure);
            app.ImagePanel = uipanel(app.Panel);
            app.ImagePanel.Title = 'Image';
            app.measurementList = uilistbox(app.ImagePanel);
            app.measurementList.Items = {};
            app.measurementList.ValueChangedFcn = createCallbackFcn(app, @updateCurrentMes, true);
            app.measurementList.Enable = 'off';
            app.measurementList.Value = {};
            app.MusclePanel = uipanel(app.Panel);
            app.MusclePanel.Title = 'Muscle';
            app.MuscleList = uilistbox(app.MusclePanel);
            app.MuscleList.Items = {};
            app.MuscleList.ValueChangedFcn = createCallbackFcn(app, @associateMuscle, true);
            app.MuscleList.Enable = 'off';
            app.MuscleList.Value = {};
            app.RegionofinterestPanel = uipanel(app.Panel);
            app.RegionofinterestPanel.Title = 'Region of interest';
            app.AddROIButton = uibutton(app.RegionofinterestPanel, 'push');
            app.AddROIButton.ButtonPushedFcn = createCallbackFcn(app, @drawROI, true);
            app.AddROIButton.Enable = 'off';
            app.AddROIButton.Text = 'Add';
            app.RemoveROIButton = uibutton(app.RegionofinterestPanel, 'push');
            app.RemoveROIButton.ButtonPushedFcn = createCallbackFcn(app, @removeROI, true);
            app.RemoveROIButton.Enable = 'off';
            app.RemoveROIButton.Text = 'Remove';
            app.dispEI = uieditfield(app.RegionofinterestPanel, 'numeric');
            app.dispEI.Editable = 'off';
            app.ThicknessPanel = uipanel(app.Panel);
            app.ThicknessPanel.Title = 'Thickness';
            app.dispTH = uieditfield(app.ThicknessPanel, 'numeric');
            app.dispTH.ValueChangedFcn = createCallbackFcn(app, @changeThicknessManually, true);
            app.dispTH.Enable = 'off';
            app.AddTHButton = uibutton(app.ThicknessPanel, 'push');
            app.AddTHButton.ButtonPushedFcn = createCallbackFcn(app, @addThickness, true);
            app.AddTHButton.Enable = 'off';
            app.AddTHButton.Text = 'Add';
            app.RemoveTHButton = uibutton(app.ThicknessPanel, 'push');
            app.RemoveTHButton.ButtonPushedFcn = createCallbackFcn(app, @removeThickness, true);
            app.RemoveTHButton.Enable = 'off';
            app.RemoveTHButton.Text = 'Remove';
            app.MeasurementSideButtonGroup = uibuttongroup(app.Panel);
            app.MeasurementSideButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @changeMeasurementSide, true);
            app.MeasurementSideButtonGroup.Enable = 'off';
            app.MeasurementSideButtonGroup.Title = 'Measurement side';
            app.LeftButton = uiradiobutton(app.MeasurementSideButtonGroup);
            app.LeftButton.Text = 'Left';
            app.RightButton = uiradiobutton(app.MeasurementSideButtonGroup);
            app.RightButton.Text = 'Right';
            app.NotspecifiedButton = uiradiobutton(app.MeasurementSideButtonGroup);
            app.NotspecifiedButton.Text = 'Not specified';
            app.NotspecifiedButton.Value = true;
            % Show the figure after all components are created
            app.QUMIAUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = QUMIA

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.QUMIAUIFigure)

            % Execute the startup function
            runStartupFcn(app, @Initialize)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.QUMIAUIFigure)
        end
    end
end