classdef normativeModels < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        NormativemodelsUIFigure      matlab.ui.Figure
        BMISwitch                    matlab.ui.control.Switch
        ModelcharacteristicsPanel_2  matlab.ui.container.Panel
        TextAreaEI                   matlab.ui.control.TextArea
        ModelcharacteristicsPanel    matlab.ui.container.Panel
        TextAreaTH                   matlab.ui.control.TextArea
        MusclesPanel                 matlab.ui.container.Panel
        ListBox                      matlab.ui.control.ListBox
        UIAxesEI                     matlab.ui.control.UIAxes
        UIAxesTH                     matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        models
        currentMuscle
        QUMIA
        config
    end
    
    methods (Access = private)
        
        function results = updateGraphics(app)
            
            % Echogenicity

            idxMdlEI = find(strcmp(app.models.EImdls.Muscle, app.currentMuscle));

            if ~isempty(idxMdlEI)

                modelDataEI = app.models.EImdls.Model{idxMdlEI}.Variables;
    
                if strcmp(app.BMISwitch.Value, 'Average BMI')
    
                    modelDataEI.csBMI(:) = mean(modelDataEI.csBMI);
                    modelDataEI.csBMI2(:) = mean(modelDataEI.csBMI2);
    
                end
    
                [modelDataEI.predEI, modelDataEI.CIEI] = predict(app.models.EImdls.Model{idxMdlEI}, modelDataEI, 'Prediction', 'observation');
                outliersEI = app.models.EImdls.outliers{idxMdlEI};
                      
                % EI
                colorCode = [0 0.4470 0.7410];
                plot(app.UIAxesEI, modelDataEI.Age(strcmp(modelDataEI.Sex, 'M') & ~outliersEI), modelDataEI.EI(strcmp(modelDataEI.Sex, 'M') & ~outliersEI), 'o', 'Color', colorCode)
                hold(app.UIAxesEI, 'on')
                plot(app.UIAxesEI, modelDataEI.Age(strcmp(modelDataEI.Sex, 'M') & ~outliersEI), modelDataEI.predEI(strcmp(modelDataEI.Sex, 'M') & ~outliersEI), '*', 'Color', colorCode)
                upper = sortrows([modelDataEI.Age(strcmp(modelDataEI.Sex, 'M') & ~outliersEI), modelDataEI.CIEI(strcmp(modelDataEI.Sex, 'M') & ~outliersEI, 2)], 1);
                lower = sortrows([modelDataEI.Age(strcmp(modelDataEI.Sex, 'M') & ~outliersEI), modelDataEI.CIEI(strcmp(modelDataEI.Sex, 'M') & ~outliersEI, 1)], 1);
                X=[upper(:, 1); flip(lower(:, 1))];
                Y=[upper(:, 2); flip(lower(:, 2))];
                fill(app.UIAxesEI, X, Y, colorCode, 'faceAlpha', 0.2, 'EdgeColor', 'none');
    
                colorCode = [0.8500 0.3250 0.0980];
                plot(app.UIAxesEI, modelDataEI.Age(strcmp(modelDataEI.Sex, 'F') & ~outliersEI), modelDataEI.EI(strcmp(modelDataEI.Sex, 'F') & ~outliersEI), 'o', 'Color', colorCode)
                plot(app.UIAxesEI, modelDataEI.Age(strcmp(modelDataEI.Sex, 'F') & ~outliersEI), modelDataEI.predEI(strcmp(modelDataEI.Sex, 'F') & ~outliersEI), '*', 'Color', colorCode)
                upper = sortrows([modelDataEI.Age(strcmp(modelDataEI.Sex, 'F') & ~outliersEI), modelDataEI.CIEI(strcmp(modelDataEI.Sex, 'F') & ~outliersEI, 2)], 1);
                lower = sortrows([modelDataEI.Age(strcmp(modelDataEI.Sex, 'F') & ~outliersEI), modelDataEI.CIEI(strcmp(modelDataEI.Sex, 'F') & ~outliersEI, 1)], 1);
                X=[upper(:, 1); flip(lower(:, 1))];
                Y=[upper(:, 2); flip(lower(:, 2))];
                fill(app.UIAxesEI, X, Y, colorCode, 'faceAlpha', 0.2, 'EdgeColor', 'none');
                legend(app.UIAxesEI, 'Male - observed','Male - predicted', 'Prediction bounds', 'Female - observed','Female - predicted', 'Prediction bounds', 'Location', 'southeast', 'NumColumns', 2)
                hold(app.UIAxesEI, 'off')
    
                app.TextAreaEI.Value = strtrim(formattedDisplayText(app.models.EImdls.Model{idxMdlEI}, 'SuppressMarkup', true));

            else

                cla(app.UIAxesEI);
                app.TextAreaEI.Value = 'No normative model available';

            end

            % Thickness
            idxMdlTH = find(strcmp(app.models.THmdls.Muscle, app.currentMuscle));

            if ~isempty(idxMdlTH)

                modelDataTH = app.models.THmdls.Model{idxMdlTH}.Variables;
    
                if strcmp(app.BMISwitch.Value, 'Average BMI')
    
                    modelDataTH.csBMI(:) = mean(modelDataTH.csBMI);
                    modelDataTH.csBMI2(:) = mean(modelDataTH.csBMI2);
    
                end
    
                [modelDataTH.predTH, modelDataTH.CITH] = predict(app.models.THmdls.Model{idxMdlTH}, modelDataTH, 'Prediction', 'observation');
                outliersTH = app.models.THmdls.outliers{idxMdlTH};
                      
                % EI
                colorCode = [0 0.4470 0.7410];
                plot(app.UIAxesTH, modelDataTH.Age(strcmp(modelDataTH.Sex, 'M') & ~outliersTH), modelDataTH.Thickness(strcmp(modelDataTH.Sex, 'M') & ~outliersTH), 'o', 'Color', colorCode)
                hold(app.UIAxesTH, 'on')
                plot(app.UIAxesTH, modelDataTH.Age(strcmp(modelDataTH.Sex, 'M') & ~outliersTH), modelDataTH.predTH(strcmp(modelDataTH.Sex, 'M') & ~outliersTH), '*', 'Color', colorCode)
                upper = sortrows([modelDataTH.Age(strcmp(modelDataTH.Sex, 'M') & ~outliersTH), modelDataTH.CITH(strcmp(modelDataTH.Sex, 'M') & ~outliersTH, 2)], 1);
                lower = sortrows([modelDataTH.Age(strcmp(modelDataTH.Sex, 'M') & ~outliersTH), modelDataTH.CITH(strcmp(modelDataTH.Sex, 'M') & ~outliersTH, 1)], 1);
                X=[upper(:, 1); flip(lower(:, 1))];
                Y=[upper(:, 2); flip(lower(:, 2))];
                fill(app.UIAxesTH, X, Y, colorCode, 'faceAlpha', 0.2, 'EdgeColor', 'none');
    
                colorCode = [0.8500 0.3250 0.0980];
                plot(app.UIAxesTH, modelDataTH.Age(strcmp(modelDataTH.Sex, 'F') & ~outliersTH), modelDataTH.Thickness(strcmp(modelDataTH.Sex, 'F') & ~outliersTH), 'o', 'Color', colorCode)
                plot(app.UIAxesTH, modelDataTH.Age(strcmp(modelDataTH.Sex, 'F') & ~outliersTH), modelDataTH.predTH(strcmp(modelDataTH.Sex, 'F') & ~outliersTH), '*', 'Color', colorCode)
                upper = sortrows([modelDataTH.Age(strcmp(modelDataTH.Sex, 'F') & ~outliersTH), modelDataTH.CITH(strcmp(modelDataTH.Sex, 'F') & ~outliersTH, 2)], 1);
                lower = sortrows([modelDataTH.Age(strcmp(modelDataTH.Sex, 'F') & ~outliersTH), modelDataTH.CITH(strcmp(modelDataTH.Sex, 'F') & ~outliersTH, 1)], 1);
                X=[upper(:, 1); flip(lower(:, 1))];
                Y=[upper(:, 2); flip(lower(:, 2))];
                fill(app.UIAxesTH, X, Y, colorCode, 'faceAlpha', 0.2, 'EdgeColor', 'none');
                legend(app.UIAxesTH, 'Male - observed','Male - predicted', 'Prediction bounds', 'Female - observed','Female - predicted', 'Prediction bounds', 'Location', 'southeast', 'NumColumns', 2)
                hold(app.UIAxesTH, 'off')
    
                app.TextAreaTH.Value = strtrim(formattedDisplayText(app.models.THmdls.Model{idxMdlTH}, 'SuppressMarkup', true));

            else

                cla(app.UIAxesTH);
                app.TextAreaTH.Value = 'No normative model available';

            end

        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function initialize(app, QUMIA, models, config)
            
            app.models = models;
            app.QUMIA = QUMIA;
            app.config = config;

            availableMuscles = readtable(app.config.muscleList);
            app.ListBox.Items = availableMuscles.Muscle;

            app.currentMuscle = app.ListBox.Items{1};
            app.ListBox.Value = app.currentMuscle;

            app.updateGraphics();

        end

        % Value changed function: ListBox
        function changeCurrentMuscle(app, event)

            app.currentMuscle = app.ListBox.Value;
            app.updateGraphics();

        end

        % Close request function: NormativemodelsUIFigure
        function NormativemodelsUIFigureCloseRequest(app, event)
            
            if isvalid(app.QUMIA)

                app.QUMIA.NormativemodelsMenu.Enable = 'on';

            end
            
            delete(app)
            
        end

        % Value changed function: BMISwitch
        function changeBMI(app, event)
            
            app.updateGraphics();

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.NormativemodelsUIFigure = uifigure('Visible', 'off');
            app.NormativemodelsUIFigure.Name = 'Normative models';
            app.NormativemodelsUIFigure.CloseRequestFcn = createCallbackFcn(app, @NormativemodelsUIFigureCloseRequest, true);
            app.UIAxesTH = uiaxes(app.NormativemodelsUIFigure);
            title(app.UIAxesTH, 'Muscle thickness')
            xlabel(app.UIAxesTH, 'Age (y)')
            ylabel(app.UIAxesTH, 'Thickness (cm)')
            zlabel(app.UIAxesTH, 'Z')
            app.UIAxesEI = uiaxes(app.NormativemodelsUIFigure);
            title(app.UIAxesEI, 'Muscle echogenicity')
            xlabel(app.UIAxesEI, 'Age (y)')
            ylabel(app.UIAxesEI, 'Echogenicity (Ipx)')
            zlabel(app.UIAxesEI, 'Z')
            app.MusclesPanel = uipanel(app.NormativemodelsUIFigure);
            app.MusclesPanel.Title = 'Muscles';
            app.ListBox = uilistbox(app.MusclesPanel);
            app.ListBox.Items = {};
            app.ListBox.ValueChangedFcn = createCallbackFcn(app, @changeCurrentMuscle, true);
            app.ListBox.Value = {};
            app.ModelcharacteristicsPanel = uipanel(app.NormativemodelsUIFigure);
            app.ModelcharacteristicsPanel.Title = 'Model characteristics';
            app.TextAreaTH = uitextarea(app.ModelcharacteristicsPanel);
            app.ModelcharacteristicsPanel_2 = uipanel(app.NormativemodelsUIFigure);
            app.ModelcharacteristicsPanel_2.Title = 'Model characteristics';
            app.TextAreaEI = uitextarea(app.ModelcharacteristicsPanel_2);
            app.BMISwitch = uiswitch(app.NormativemodelsUIFigure, 'slider');
            app.BMISwitch.Items = {'Individual BMI', 'Average BMI'};
            app.BMISwitch.ValueChangedFcn = createCallbackFcn(app, @changeBMI, true);
            app.BMISwitch.Value = 'Average BMI';
            % Show the figure after all components are created
            app.NormativemodelsUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = normativeModels(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.NormativemodelsUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)initialize(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.NormativemodelsUIFigure)
        end
    end
end