classdef patientCharacteristics < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        PatientCharacteristicsUIFigure  matlab.ui.Figure
        TechnicianEditField             matlab.ui.control.EditField
        TechnicianEditFieldLabel        matlab.ui.control.Label
        SavecontinueButton              matlab.ui.control.Button
        HandednessButtonGroup           matlab.ui.container.ButtonGroup
        HandednessLabel                 matlab.ui.control.Label
        UnknownButton                   matlab.ui.control.RadioButton
        LeftButton                      matlab.ui.control.RadioButton
        RightButton                     matlab.ui.control.RadioButton
        SexButtonGroup                  matlab.ui.container.ButtonGroup
        SexLabel                        matlab.ui.control.Label
        MaleButton                      matlab.ui.control.RadioButton
        FemaleButton                    matlab.ui.control.RadioButton
        WeightkgEditField               matlab.ui.control.NumericEditField
        WeightkgEditFieldLabel          matlab.ui.control.Label
        LengthcmEditField               matlab.ui.control.NumericEditField
        LengthcmEditFieldLabel          matlab.ui.control.Label
        DateofBirthDatePicker           matlab.ui.control.DatePicker
        DateofBirthDatePickerLabel      matlab.ui.control.Label
        IDEditField                     matlab.ui.control.EditField
        IDEditFieldLabel                matlab.ui.control.Label
    end

    
    properties (Access = private)

        QUMIA % Main app
        subject

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, QUMIA, subject)
            
            app.QUMIA = QUMIA;
            app.subject = subject;

            app.IDEditField.Value = app.subject.ID;
            app.DateofBirthDatePicker.Value = app.subject.DoB;
            app.WeightkgEditField.Value = app.subject.Weight;
            app.LengthcmEditField.Value = app.subject.Length;

            handedNessButton = find(strcmp({app.HandednessButtonGroup.Buttons.Text}, app.subject.Handedness));
            app.HandednessButtonGroup.Buttons(handedNessButton).Value = 1;
            sexButton = find(strcmp({app.SexButtonGroup.Buttons.Text}, app.subject.Sex));
            app.SexButtonGroup.Buttons(sexButton).Value = 1;
            
            app.TechnicianEditField.Value = app.subject.Technician;

        end

        % Button pushed function: SavecontinueButton
        function saveAndContinue(app, event)
            
            app.PatientCharacteristicsUIFigureCloseRequest();

        end

        % Value changed function: IDEditField
        function changeSubjectID(app, event)

            app.subject.ID = num2str(app.IDEditField.Value);

        end

        % Value changed function: DateofBirthDatePicker
        function changeSubjectDoB(app, event)

            app.subject.DoB = datetime(app.DateofBirthDatePicker.Value, 'Inputformat', 'yyyy.MM.dd');
            
        end

        % Value changed function: LengthcmEditField
        function changeSubjectLength(app, event)
            
            app.subject.Length = app.LengthcmEditField.Value;

        end

        % Value changed function: WeightkgEditField
        function changeSubjectWeight(app, event)
            
            app.subject.Weight = app.WeightkgEditField.Value;
            
        end

        % Selection changed function: SexButtonGroup
        function changeSubjectSex(app, event)
            
            app.subject.Sex = app.SexButtonGroup.SelectedObject.Text;
            
        end

        % Selection changed function: HandednessButtonGroup
        function changeSubjectHandedness(app, event)
            
            app.subject.Handedness = app.HandednessButtonGroup.SelectedObject.Text;

        end

        % Value changed function: TechnicianEditField
        function changeTechnician(app, event)

            app.subject.Technician = app.TechnicianEditField.Value;
            
        end

        % Close request function: PatientCharacteristicsUIFigure
        function PatientCharacteristicsUIFigureCloseRequest(app, event)
            
            if isempty(app.subject.ID) | isempty(app.subject.DoB) | isempty(app.subject.Length) |...
               isempty(app.subject.Weight) | isempty(app.subject.Sex) | isempty(app.subject.Handedness) | isempty(app.subject.Technician)

                uialert(app.PatientCharacteristicsUIFigure, "Not all subject characteristics were entered", "Incomplete data",  'Icon', '');

            else

                updateSubject(app.QUMIA, app.subject);

                if isvalid(app.QUMIA)

                    app.QUMIA.PatientcharacteristicsMenu.Enable = 'on';
                    app.QUMIA.SaveMenu.Enable = 'on';
                    app.QUMIA.ReportpreviewMenu.Enable = 'on';
                    app.QUMIA.MuscleList.Enable = 'on';
                    app.QUMIA.measurementList.Enable = 'on';
                    app.QUMIA.MeasurementSideButtonGroup.Enable = 'on';
                    app.QUMIA.AddROIButton.Enable = 'on';
                    app.QUMIA.RemoveROIButton.Enable = 'on';
                    app.QUMIA.AddTHButton.Enable = 'on';
                    app.QUMIA.RemoveTHButton.Enable = 'on';
                    app.QUMIA.dispTH.Enable = 'on';
    
                end
                
                delete(app)

            end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.PatientCharacteristicsUIFigure = uifigure('Visible', 'off');
            app.PatientCharacteristicsUIFigure.Name = 'Patient Characteristics';
            app.PatientCharacteristicsUIFigure.CloseRequestFcn = createCallbackFcn(app, @PatientCharacteristicsUIFigureCloseRequest, true);
            app.IDEditFieldLabel = uilabel(app.PatientCharacteristicsUIFigure);
            app.IDEditFieldLabel.Text = 'ID';
            app.IDEditField = uieditfield(app.PatientCharacteristicsUIFigure, 'text');
            app.IDEditField.ValueChangedFcn = createCallbackFcn(app, @changeSubjectID, true);
            app.IDEditField.Value = '0';
            app.DateofBirthDatePickerLabel = uilabel(app.PatientCharacteristicsUIFigure);
            app.DateofBirthDatePickerLabel.Text = 'Date of Birth';
            app.DateofBirthDatePicker = uidatepicker(app.PatientCharacteristicsUIFigure);
            app.DateofBirthDatePicker.DisplayFormat = 'dd.MM.uuuu';
            app.DateofBirthDatePicker.ValueChangedFcn = createCallbackFcn(app, @changeSubjectDoB, true);
            app.LengthcmEditFieldLabel = uilabel(app.PatientCharacteristicsUIFigure);
            app.LengthcmEditFieldLabel.Text = 'Length (cm)';
            app.LengthcmEditField = uieditfield(app.PatientCharacteristicsUIFigure, 'numeric');
            app.LengthcmEditField.ValueChangedFcn = createCallbackFcn(app, @changeSubjectLength, true);
            app.WeightkgEditFieldLabel = uilabel(app.PatientCharacteristicsUIFigure);
            app.WeightkgEditFieldLabel.Text = 'Weight (kg)';
            app.WeightkgEditField = uieditfield(app.PatientCharacteristicsUIFigure, 'numeric');
            app.WeightkgEditField.ValueChangedFcn = createCallbackFcn(app, @changeSubjectWeight, true);
            app.SexButtonGroup = uibuttongroup(app.PatientCharacteristicsUIFigure);
            app.SexButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @changeSubjectSex, true);
            app.FemaleButton = uiradiobutton(app.SexButtonGroup);
            app.FemaleButton.Text = 'Female';
            app.MaleButton = uiradiobutton(app.SexButtonGroup);
            app.MaleButton.Text = 'Male';
            app.MaleButton.Value = true;
            app.SexLabel = uilabel(app.SexButtonGroup);
            app.SexLabel.Text = 'Sex';
            app.HandednessButtonGroup = uibuttongroup(app.PatientCharacteristicsUIFigure);
            app.HandednessButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @changeSubjectHandedness, true);
            app.RightButton = uiradiobutton(app.HandednessButtonGroup);
            app.RightButton.Text = 'Right';
            app.LeftButton = uiradiobutton(app.HandednessButtonGroup);
            app.LeftButton.Text = 'Left';
            app.UnknownButton = uiradiobutton(app.HandednessButtonGroup);
            app.UnknownButton.Text = 'Unknown';
            app.UnknownButton.Value = true;
            app.HandednessLabel = uilabel(app.HandednessButtonGroup);
            app.HandednessLabel.Text = 'Handedness';
            app.SavecontinueButton = uibutton(app.PatientCharacteristicsUIFigure, 'push');
            app.SavecontinueButton.ButtonPushedFcn = createCallbackFcn(app, @saveAndContinue, true);
            app.SavecontinueButton.Text = 'Save & continue';
            app.TechnicianEditFieldLabel = uilabel(app.PatientCharacteristicsUIFigure);
            app.TechnicianEditFieldLabel.Text = 'Technician';
            app.TechnicianEditField = uieditfield(app.PatientCharacteristicsUIFigure, 'text');
            app.TechnicianEditField.ValueChangedFcn = createCallbackFcn(app, @changeTechnician, true);
            app.TechnicianEditField.Value = '0';
            % Show the figure after all components are created
            app.PatientCharacteristicsUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = patientCharacteristics(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.PatientCharacteristicsUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.PatientCharacteristicsUIFigure)
        end
    end
end