classdef reportPreview < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ReportpreviewUIFigure  matlab.ui.Figure
        ShowindividualmeasurementsCheckBox  matlab.ui.control.CheckBox
        PreviewTable           matlab.ui.control.Table
        UIAxes                 matlab.ui.control.UIAxes
    end

    
    properties (Access = private)

        QUMIA                                                               % Main app
        report                                                              % Report structure
        bodyIm
        bodyChartCoords

    end
    
    methods (Access = private)
        
        function results = fillTable(app)
            
            if ~app.ShowindividualmeasurementsCheckBox.Value
    
                t = table(app.report.Muscle, app.report.Side, ...
                          app.report.avgEI, app.report.predictedEI, app.report.zScoreEI, ...
                          app.report.avgTH, app.report.predictedTH, app.report.zScoreTH);
                t.Properties.VariableNames = {'Muscle', 'Side', 'EI - observed', 'EI - predicted', 'EI Z score', 'TH - observed', 'TH - predicted', 'TH - Z score'};

            else

                measEI = app.report.measurementsEI;
                maxCols = max(cellfun(@numel, measEI));
                expandedMeasEI = NaN(numel(measEI), maxCols);

                for i = 1:numel(measEI)

                    cellContent = measEI{i};

                    if ~isempty(cellContent)

                        expandedMeasEI(i, 1:numel(cellContent)) = cellContent;

                    end

                end

                tMeasEI = array2table(expandedMeasEI, 'VariableNames', strcat('EI_', string(1:maxCols)));

                measTH = app.report.measurementsTH;
                maxCols = max(cellfun(@numel, measTH));
                expandedMeasTH = NaN(numel(measTH), maxCols);

                for i = 1:numel(measTH)

                    cellContent = measTH{i};

                    if ~isempty(cellContent)

                        expandedMeasTH(i, 1:numel(cellContent)) = cellContent;

                    end

                end
                
                tMeasTH = array2table(expandedMeasTH, 'VariableNames', strcat('TH_', string(1:maxCols)));

                t = [table(app.report.Muscle, app.report.Side, 'VariableNames', {'Muscle', 'Side'}) ...
                           tMeasEI ...
                           table(app.report.avgEI, app.report.predictedEI, app.report.zScoreEI, 'VariableNames', {'EI - observed', 'EI - predicted', 'EI Z score'}) ...
                           tMeasTH ...
                           table(app.report.avgTH, app.report.predictedTH, app.report.zScoreTH, 'VariableNames', {'TH - observed', 'TH - predicted', 'TH - Z score'})];

            end

            app.PreviewTable.Data = t;
            app.PreviewTable.ColumnName = t.Properties.VariableNames;   
            
        end

        
        function results = fillInBodyChart(app)

            annotatedBodyIm = app.bodyIm;

            for i = 1:numel(app.report.Side)

                if ~isempty(find(strcmp(app.bodyChartCoords.muscle, app.report.Muscle{i})))

                    muscleIdx = find(strcmp(app.bodyChartCoords.muscle, app.report.Muscle{i}));

                    if strcmp(app.report.Side{i}, 'Right')
                        
                        annotatedBodyIm(app.bodyChartCoords.maskR{muscleIdx}) = app.getAnnotationValue(app.report.zScoreEI(i));
    
                    elseif strcmp(app.report.Side{i}, 'Left')

                        annotatedBodyIm(app.bodyChartCoords.maskL{muscleIdx}) = app.getAnnotationValue(app.report.zScoreEI(i));
    
                    end

                end

            end
            
            imagesc(app.UIAxes, annotatedBodyIm);

        end

        
        function annotation = getAnnotationValue(app, zScore)

            if zScore >= 3.0

                annotation = 50;
    
            elseif zScore >= 2.5
    
                annotation = 100;
    
            elseif zScore >= 2.0
    
                annotation = 150;
    
            elseif zScore >= 1.5
    
                annotation = 200;
    
            else
    
                annotation = 255;
    
            end
            
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, QUMIA, report)
            
            app.QUMIA = QUMIA;
            app.report = report;

            app.UIAxes.XColor = 'white';
            app.UIAxes.YColor = 'white';
            app.bodyIm = imread('BodyChart.jpg');
            imagesc(app.UIAxes, app.bodyIm);

            loadObj = load('bodyChartCoords.mat');
            app.bodyChartCoords = loadObj.coords;

            app.fillTable();
            app.fillInBodyChart();

        end

        % Close request function: ReportpreviewUIFigure
        function ReportpreviewUIFigureCloseRequest(app, event)
            
            if isvalid(app.QUMIA)

                app.QUMIA.ReportpreviewMenu.Enable = 'on';

            end
            
            delete(app);
            
        end

        % Value changed function: ShowindividualmeasurementsCheckBox
        function toggleIndividualMeasurements(app, event)

            app.fillTable()
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ReportpreviewUIFigure = uifigure('Visible', 'off');
            app.ReportpreviewUIFigure.Name = 'Report preview';
            app.ReportpreviewUIFigure.CloseRequestFcn = createCallbackFcn(app, @ReportpreviewUIFigureCloseRequest, true);
            app.UIAxes = uiaxes(app.ReportpreviewUIFigure);
            app.UIAxes.XLim = [1 300];
            app.UIAxes.YLim = [1 340];
            app.UIAxes.ZLim = [0 1];
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes.ZTick = [];
            app.PreviewTable = uitable(app.ReportpreviewUIFigure);
            app.PreviewTable.ColumnName = {'Muscle'; 'Side'; 'EI - observed'; 'EI- predicted'; 'EI Z score'; 'TH - observed'; 'TH - predicted'; 'TH - Z score'};
            app.PreviewTable.RowName = {};
            app.PreviewTable.ColumnSortable = [true true true true true true true false];
            app.ShowindividualmeasurementsCheckBox = uicheckbox(app.ReportpreviewUIFigure);
            app.ShowindividualmeasurementsCheckBox.ValueChangedFcn = createCallbackFcn(app, @toggleIndividualMeasurements, true);
            app.ShowindividualmeasurementsCheckBox.Text = 'Show individual measurements';
            % Show the figure after all components are created
            app.ReportpreviewUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = reportPreview(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ReportpreviewUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ReportpreviewUIFigure)
        end
    end
end